classdef project_finalunga < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        VaccinationrateEditField_2     matlab.ui.control.NumericEditField
        VaccinationrateEditField_2Label  matlab.ui.control.Label
        SimulateButton_2               matlab.ui.control.Button
        PopulationEditField            matlab.ui.control.NumericEditField
        PopulationEditFieldLabel       matlab.ui.control.Label
        DaysEditField_2                matlab.ui.control.NumericEditField
        DaysEditField_2Label           matlab.ui.control.Label
        DaysEditField                  matlab.ui.control.NumericEditField
        DaysEditFieldLabel             matlab.ui.control.Label
        VaccinationrateEditField       matlab.ui.control.NumericEditField
        VaccinationrateEditFieldLabel  matlab.ui.control.Label
        GammaEditField                 matlab.ui.control.NumericEditField
        GammaEditFieldLabel            matlab.ui.control.Label
        BetaEditField                  matlab.ui.control.NumericEditField
        BetaEditFieldLabel             matlab.ui.control.Label
        SettheParameterLabel           matlab.ui.control.Label
        chooseadatasetLabel            matlab.ui.control.Label
        SimulateButton                 matlab.ui.control.Button
        DataDropDown                   matlab.ui.control.DropDown
        DataDropDownLabel              matlab.ui.control.Label
        Process02Label                 matlab.ui.control.Label
        Process01Label                 matlab.ui.control.Label
        TheSIRVModelLabel              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            clc;close all;
switch app.DataDropDown.Value
    case 'China'
      load("C:\Users\User\OneDrive - BUET\Level-2 Term-1\EEE 212- Matlab Lab\prj\SIR_China.mat");
      Data=SIRChinaHubei;
    case 'France'
        load("C:\Users\User\OneDrive - BUET\Level-2 Term-1\EEE 212- Matlab Lab\prj\SIR_France.mat");
        Data=SIRFrance;
    case 'Germany'
        load("C:\Users\User\OneDrive - BUET\Level-2 Term-1\EEE 212- Matlab Lab\prj\SIR_Germany2.mat");
        Data=SIRGermany2;
    case 'Italy'
       load("C:\Users\User\OneDrive - BUET\Level-2 Term-1\EEE 212- Matlab Lab\prj\SIR_Italy.mat");
       Data=SIRItaly;
    case 'Japan'
        load("C:\Users\User\OneDrive - BUET\Level-2 Term-1\EEE 212- Matlab Lab\prj\JAPAN.mat");
        Data=SIRJapan;
    case 'New-York'
       load("C:\Users\HP\OneDrive - BUET\level 2 term 01\EEE 212 MATLAB\project\app\SIR_Newyork.mat");
       Data=SIRNewYork;
    case 'Spain'
        load("C:\Users\User\OneDrive - BUET\Level-2 Term-1\EEE 212- Matlab Lab\prj\SIR_Spain.mat");
        Data=SIRSpain;
    case 'USA'
        load("C:\Users\HP\OneDrive - BUET\level 2 term 01\EEE 212 MATLAB\project\app\SIR_USA.mat");
        Data=SIRUSAWashington;
    case 'India'
        load("C:\Users\HP\OneDrive - BUET\level 2 term 01\EEE 212 MATLAB\project\app\SIR_india.mat");
        Data=SIRIndia;
end
S=table2array(Data(:,2));
I=table2array(Data(:,3));
R=table2array(Data(:,4));
sz=size(S);
sz=max(sz);
beta=zeros(1,sz-1);
gamma=zeros(1,sz-1);
indx=find(I>0,1,"first");
for i=indx:sz-1
    beta(i)=((I(i+1)-I(i))+(R(i+1)-R(i)))/I(i);
    gamma(i)=(R(i+1)-R(i))/I(i);
end
avg_beta=sum(beta)/(sz-1);
avg_gamma=sum(gamma)/(sz-1);

%function initialization
sirvI=@(ys,yi,beta,gama,N) (beta*ys*yi)/N-(gama*yi);
sirvR=@(yi,yr,gama) (gama*yi);
sirvS=@(ys,yi,beta,ro,yr,N) (-beta*ys*yi)/N-(ro*ys);
sirvV=@(ys,ro) ro*ys;
%initialize parameter
a=0;            %starting point
b=app.DaysEditField.Value; %end point
h=0.001;         %step size   
n=(b-a)/h;      %partition number
x=a:h:b;         
N=S(1)+I(1)+R(1);       %Total population
beta=avg_beta;     %rate of infection
gama=avg_gamma;    %rate of recovery
ro=app.VaccinationrateEditField_2.Value;       %rate of vaccinization
iniI=0.2*N;
iniR=0.05*N;
iniV=0;
iniS=N-iniI-iniR -iniV;
%s: suspectible
ys=zeros(1,n+1);
ys(1)=iniS;
%i: infectous
yi=zeros(1,n+1);
yi(1)=iniI;
%r: recovered
yr=zeros(1,n+1);
yr(1)=iniR;
%v: vaccinised
yv=zeros(1,n+1);
yv(1)=iniV;
%calculating by RK method
for i=1:n
%K1
s_k1=sirvS(ys(i),yi(i),beta,ro,yr(i),N);
i_k1=sirvI(ys(i),yi(i),beta,gama,N);
r_k1=sirvR(yi(i),yr(i),gama);
v_k1=sirvV(ys(i),ro);
%K2
s_k2=sirvS(ys(i)+(0.5*s_k1*h),yi(i)+(0.5*i_k1*h),beta,ro,yr(i)+(0.5*r_k1*h),N);
i_k2=sirvI(ys(i)+(0.5*s_k1*h),yi(i)+(0.5*i_k1*h),beta,gama,N);
r_k2=sirvR(yi(i)+(0.5*i_k1*h),yr(i)+(0.5*r_k1*h),gama);
v_k2=sirvV(ys(i)+(0.5*s_k1*h),ro);
%K3
s_k3=sirvS(ys(i)+(0.5*s_k2*h),yi(i)+(0.5*i_k2*h),beta,ro,yr(i)+(0.5*r_k2*h),N);
i_k3=sirvI(ys(i)+(0.5*s_k2*h),yi(i)+(0.5*i_k2*h),beta,gama,N);
r_k3=sirvR(yi(i)+(0.5*i_k2*h),yr(i)+(0.5*r_k2*h),gama);
v_k3=sirvV(ys(i)+(0.5*s_k2*h),ro);
%K4
s_k4=sirvS(ys(i)+(s_k3*h),yi(i)+(i_k3*h),beta,ro,yr(i)+(r_k3*h),N);
i_k4=sirvI(ys(i)+(s_k3*h),yi(i)+(i_k3*h),beta,gama,N);
r_k4=sirvR(yi(i)+(i_k3*h),yr(i)+(r_k3*h),gama);
v_k4=sirvV(ys(i)+(s_k3*h),ro);
%y(i+1)
ys(i+1)=ys(i)+((h/6)*(s_k1+(2*s_k2)+(2*s_k3)+s_k4));
yi(i+1)=yi(i)+((h/6)*(i_k1+(2*i_k2)+(2*i_k3)+i_k4));
yr(i+1)=yr(i)+((h/6)*(r_k1+(2*r_k2)+(2*r_k3)+r_k4));
yv(i+1)=yv(i)+((h/6)*(v_k1+(2*v_k2)+(2*v_k3)+v_k4));
end

nm=app.DataDropDown.Value;
plot(x,ys,'b',x,yi,'r',x,yr,'g',x,yv,'black',LineWidth=2);
title("SIRV Model By Dataset",nm,"FontSize",15)
xlabel('Time(in days)',FontSize=15);
legend('S','I','R','V',fontsize=15);

        end

        % Button pushed function: SimulateButton_2
        function SimulateButton_2Pushed(app, event)
            clc;close all;
            %function initialization
sirvI=@(ys,yi,beta,gama,N) (beta*ys*yi)/N-(gama*yi);
sirvR=@(yi,yr,gama) (gama*yi);
sirvS=@(ys,yi,beta,ro,yr,N) (-beta*ys*yi)/N-(ro*ys);
sirvV=@(ys,ro) ro*ys;
%initialize parameter
a=0;            %starting point
b=app.DaysEditField_2.Value; %end point
h=0.01;         %step size   
n=(b-a)/h;      %partition number
x=a:h:b;         
N=app.PopulationEditField.Value;       %Total population
beta=app.BetaEditField.Value;     %rate of infection
gama=app.GammaEditField.Value;    %rate of recovery
ro=app.VaccinationrateEditField.Value;       %rate of vaccinization
iniI=0.1*N;
iniR=0.05*N;
iniV=0;
iniS=N-iniI-iniR-iniV;
%s: suspectible
ys=zeros(1,n+1);
ys(1)=iniS;
%i: infectous
yi=zeros(1,n+1);
yi(1)=iniI;
%r: recovered
yr=zeros(1,n+1);
yr(1)=iniR;
%v: vaccinised
yv=zeros(1,n+1);
yv(1)=iniV;
%calculating by RK method
for i=1:n
%K1
s_k1=sirvS(ys(i),yi(i),beta,ro,yr(i),N);
i_k1=sirvI(ys(i),yi(i),beta,gama,N);
r_k1=sirvR(yi(i),yr(i),gama);
v_k1=sirvV(ys(i),ro);
%K2
s_k2=sirvS(ys(i)+(0.5*s_k1*h),yi(i)+(0.5*i_k1*h),beta,ro,yr(i)+(0.5*r_k1*h),N);
i_k2=sirvI(ys(i)+(0.5*s_k1*h),yi(i)+(0.5*i_k1*h),beta,gama,N);
r_k2=sirvR(yi(i)+(0.5*i_k1*h),yr(i)+(0.5*r_k1*h),gama);
v_k2=sirvV(ys(i)+(0.5*s_k1*h),ro);
%K3
s_k3=sirvS(ys(i)+(0.5*s_k2*h),yi(i)+(0.5*i_k2*h),beta,ro,yr(i)+(0.5*r_k2*h),N);
i_k3=sirvI(ys(i)+(0.5*s_k2*h),yi(i)+(0.5*i_k2*h),beta,gama,N);
r_k3=sirvR(yi(i)+(0.5*i_k2*h),yr(i)+(0.5*r_k2*h),gama);
v_k3=sirvV(ys(i)+(0.5*s_k2*h),ro);
%K4
s_k4=sirvS(ys(i)+(s_k3*h),yi(i)+(i_k3*h),beta,ro,yr(i)+(r_k3*h),N);
i_k4=sirvI(ys(i)+(s_k3*h),yi(i)+(i_k3*h),beta,gama,N);
r_k4=sirvR(yi(i)+(i_k3*h),yr(i)+(r_k3*h),gama);
v_k4=sirvV(ys(i)+(s_k3*h),ro);
%y(i+1)
ys(i+1)=ys(i)+((h/6)*(s_k1+(2*s_k2)+(2*s_k3)+s_k4));
yi(i+1)=yi(i)+((h/6)*(i_k1+(2*i_k2)+(2*i_k3)+i_k4));
yr(i+1)=yr(i)+((h/6)*(r_k1+(2*r_k2)+(2*r_k3)+r_k4));
yv(i+1)=yv(i)+((h/6)*(v_k1+(2*v_k2)+(2*v_k3)+v_k4));
end


plot(x,ys,'b',x,yi,'r',x,yr,'g',x,yv,'black',LineWidth=2);
title("SIRV Model By setting the parameter","FontSize",10);
xlabel('Time(in days)',FontSize=15);
legend('S','I','R','V',fontsize=15);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TheSIRVModelLabel = uilabel(app.UIFigure);
            app.TheSIRVModelLabel.Text = 'The SIRV Model';
            app.Process01Label = uilabel(app.UIFigure);
            app.Process01Label.Text = 'Process 01';
            app.Process02Label = uilabel(app.UIFigure);
            app.Process02Label.Text = 'Process 02';
            app.DataDropDownLabel = uilabel(app.UIFigure);
            app.DataDropDownLabel.Text = 'Data';
            app.DataDropDown = uidropdown(app.UIFigure);
            app.DataDropDown.Items = {'China', 'France', 'Germany', 'Italy', 'Japan', 'New-York', 'Spain', 'USA', 'India'};
            app.DataDropDown.Value = 'China';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.chooseadatasetLabel = uilabel(app.UIFigure);
            app.chooseadatasetLabel.Text = 'choose a dataset ';
            app.SettheParameterLabel = uilabel(app.UIFigure);
            app.SettheParameterLabel.Text = 'Set the Parameter';
            app.BetaEditFieldLabel = uilabel(app.UIFigure);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.GammaEditFieldLabel = uilabel(app.UIFigure);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.UIFigure, 'numeric');
            app.VaccinationrateEditFieldLabel = uilabel(app.UIFigure);
            app.VaccinationrateEditFieldLabel.Text = 'Vaccination rate';
            app.VaccinationrateEditField = uieditfield(app.UIFigure, 'numeric');
            app.DaysEditFieldLabel = uilabel(app.UIFigure);
            app.DaysEditFieldLabel.Text = 'Days';
            app.DaysEditField = uieditfield(app.UIFigure, 'numeric');
            app.DaysEditField_2Label = uilabel(app.UIFigure);
            app.DaysEditField_2Label.Text = 'Days';
            app.DaysEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PopulationEditFieldLabel = uilabel(app.UIFigure);
            app.PopulationEditFieldLabel.Text = 'Population';
            app.PopulationEditField = uieditfield(app.UIFigure, 'numeric');
            app.SimulateButton_2 = uibutton(app.UIFigure, 'push');
            app.SimulateButton_2.ButtonPushedFcn = createCallbackFcn(app, @SimulateButton_2Pushed, true);
            app.SimulateButton_2.Text = 'Simulate';
            app.VaccinationrateEditField_2Label = uilabel(app.UIFigure);
            app.VaccinationrateEditField_2Label.Text = 'Vaccination rate';
            app.VaccinationrateEditField_2 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project_finalunga

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end